classdef correspondance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        RegionSpinnerLabel        matlab.ui.control.Label
        RegionSpinner             matlab.ui.control.Spinner
        ChannelSpinnerLabel       matlab.ui.control.Label
        ChannelSpinner            matlab.ui.control.Spinner
        TimeSpinnerLabel          matlab.ui.control.Label
        TimeSpinner               matlab.ui.control.Spinner
        LoadButton                matlab.ui.control.Button
        RedoCorrespondanceButton  matlab.ui.control.Button
        MissedcellsTextAreaLabel  matlab.ui.control.Label
        MissedcellsTextArea       matlab.ui.control.TextArea
        GOBACKButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        rects           % Rectangle limits
        posLookup       % Map from region to position
        reader          % ND2 File Reader
        res             % Result file
        oldImgHandle    % Image handle for previous frame
        newImgHandle    % Image handle for current frame
        cmap            % Colormap for image display
        backup          % Backup masks for going back
    end
    
    methods (Access = private)
        
        function mask = getSegMask(app, xy, t)
            % Returns cell segmentation mask of desired position,
            % or current position if not specified
            if nargin==1
                xy = app.RegionSpinner.Value;
                t = app.TimeSpinner.Value;
            end
            
            if (t<1 || xy<1)
                mask = [];
                return
            end
            
            subcell = app.res.segMasks(xy, 1, t);
            mask = uint16(subcell{1});
        end
        
        function [] = setSegMask(app, mask, xy, t)
            % Sets cell segmentation mask of desired position,
            % or current position if not specified
            if nargin <= 2
                xy = app.RegionSpinner.Value;
                t = app.TimeSpinner.Value;
            end
            
            subcell = cell(1);
            subcell{1} = mask;
            app.res.segMasks(xy, 1, t) = subcell;
        end
    
        function [] = loadNewFile(app)
            % Find and read file
            [filename, path] = uigetfile('*.mldatx', 'Select file to load.');
            app.res = matfile(fullfile(path, filename), 'Writable', true);
            
            % Extract relevant metadata from file
            meta = app.res.meta;
            app.rects = meta.rects;
            app.posLookup = meta.posLookup;

            % Create ND2 File Reader
            bfInitLogging('OFF');
            app.reader = loci.formats.Memoizer(bfGetReader(), 0);
            app.reader.setId(meta.imgfile);
            
            % Initialize Spinners
            app.ChannelSpinner.Value = 1;
            app.TimeSpinner.Value = 1;
            app.RegionSpinner.Value = 1;
            app.TimeSpinner.Limits = [1, app.reader.getSizeT];
            app.ChannelSpinner.Limits = [1, app.reader.getSizeC];
            if length(app.rects)==1
                app.RegionSpinner.Enable = false;
            else
                app.RegionSpinner.Limits = [1, length(app.rects)];
            end
        end
    
        function cm = createColorMap(app)
            % Create colormap that is random in the blue/yellow spectrum until 9000 and red after
            unshuffled = colormap(app.UIAxes, 'parula');
            shuffled = unshuffled(randperm(size(unshuffled, 1)), :);
            cm = [repmat(shuffled, 140, 1); repmat([[1,0,0]], 1000, 1)];
        end
    
        function clickCallBack(app, e)
            x = uint16(e.IntersectionPoint(1));
            y = uint16(e.IntersectionPoint(2));      
            
            input = inputdlg('Enter new cell number');
            newNb = str2num(input{1});
            if isempty(newNb)
                return
            end
            currMask = getSegMask(app);
            app.backup = currMask;
            regionNb = currMask(y, x);
            
            currMask(currMask == regionNb) = newNb;
            setSegMask(app, currMask);
            displayImg(app)
        end
    
        function out = addNumbers(app, img, xy, t)
            % Adds cell numbers on image display
            segMask = getSegMask(app, xy, t);
            if isempty(segMask)
                out = img;
                return
            end
            
            cells=unique(segMask)';
            x = zeros(length(cells), 1);
            y = zeros(length(cells), 1);
            for i=1:length(cells)
                cell=cells(i);
                % Dont label bg and borders
                if cell <= 0
                    continue
                end
                
                cellMask = segMask==cell;
                
                % Approximate centroid using 10 randomly sampled pixels of the segment
                linidx = find(cellMask);
                sampleidx = randsample(linidx, 30, true);
                [rowi, coli] = ind2sub(size(cellMask), sampleidx);
                x(i) = mean(coli);
                y(i) = mean(rowi);
            end
            out = insertText(img, [x, y], cells, 'FontSize', 16, 'TextColor', 'g',...
                             'BoxOpacity', 0, 'AnchorPoint', 'Center');
        end
    
        function out = addSegMasks(app, img, xy, t)
            segMask = getSegMask(app, xy, t);
            if isempty(segMask)
                out = cat(3, img, img, img);
                return
            end
            
            rgbLabels = label2rgb(segMask, app.cmap);
            out = img * .7 + rgbLabels * .3;
        end
    
        function withlab = getDisplayableImg(app, xy, t)
            im = getFrame(app, app.ChannelSpinner.Value, xy, t);
            im8bit = uint8(rescale(im, 0, 255));
            withmask = addSegMasks(app, im8bit, xy, t);
            withlab = addNumbers(app, withmask, xy, t);
        end
    
        function [] = displayImg(app)
            % Get pos
            xy = app.RegionSpinner.Value;
            t = app.TimeSpinner.Value;
            
            % FOR CURRENT IMAGE
            new = getDisplayableImg(app, xy, t);
            if isempty(app.newImgHandle)
                app.newImgHandle = imshow(new, 'Parent', app.UIAxes2);
                app.newImgHandle.ButtonDownFcn = @(~, e) clickCallBack(app, e);
            else 
                app.newImgHandle.CData = new;
            end
            
            % FOR PREVIOUS FRAME
            if t==1
                old = zeros(size(new));
            else
                old = getDisplayableImg(app, xy, t-1);
            end
            if isempty(app.oldImgHandle)
                app.oldImgHandle = imshow(old, 'Parent', app.UIAxes);
            else 
                app.oldImgHandle.CData = old;
            end            
        end
    
        function img = getFrame(app, channel, xy, t)
            % Gets current frame in specified channel. Default values are selected
            if nargin <= 1
                xy = app.RegionSpinner.Value;
                t = app.TimeSpinner.Value;
            end
            
            series = app.posLookup(xy);
            app.reader.setSeries(series - 1);
            
            rect = app.rects{xy};
            ix = app.reader.getIndex(0, channel- 1, t- 1) + 1;
            img = bfGetPlane(app.reader, ix, rect(1), rect(2), rect(3), rect(4));
        end
    
        function [] = updateCellFluo(app, xy, t)
            % Updates cell fluorescence for all channels at the given
            % position (or the current position if no other is specified)
            if nargin <= 1
                xy = app.RegionSpinner.Value;
                t = app.TimeSpinner.Value;
            end
            
            segMask = getSegMask(app, xy, t);
            if isempty(segMask)
                return
            end
            
            for col = 1:app.reader.getSizeC                
                frame = getFrame(app, col, xy, t);
                
                % Calculate Area and Fluorescence using Splitapply                
                [g, id] = findgroups(segMask(:));
                inner = @(a) deal(length(a), sum(a));
                [c, s] = splitapply(inner, frame(:), g);
                
                maxCellNbr = max(segMask(:));
                fluo = NaN(maxCellNbr,1);
                area = NaN(maxCellNbr,1);
                nobg = id>0;
                fluo(id(nobg)) = s(nobg);
                area(id(nobg)) = c(nobg);
                
                % Write to disk
                subcell = cell(1);
                subcell{1} = fluo;
                app.res.cellFluo(xy, 1, t, col) = subcell;
                
                subcell = cell(1);
                subcell{1} = area;
                app.res.cellArea(xy, 1, t, col) = subcell;
            end            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadNewFile(app)
            app.cmap = createColorMap(app);
            displayImg(app)
        end

        % Button pushed function: LoadButton
        function doLoad(app, event)
            loadNewFile(app)
        end

        % Value changed function: RegionSpinner
        function doChangeRegion(app, event)
            displayImg(app)
            app.backup = [];
            app.MissedcellsTextArea.Value = "";
            updateCellFluo(app, event.PreviousValue, app.TimeSpinner.Value);
        end

        % Value changed function: TimeSpinner
        function doChangeTime(app, event)
            displayImg(app)
            app.backup = [];
            app.MissedcellsTextArea.Value = "";
            updateCellFluo(app, app.RegionSpinner.Value, event.PreviousValue);
        end

        % Value changed function: ChannelSpinner
        function doChangeChannel(app, event)
            displayImg(app)
        end

        % Button pushed function: RedoCorrespondanceButton
        function doCorrespondance(app, event)
            currMask = getSegMask(app);
            prevMask = getSegMask(app, app.RegionSpinner.Value, app.TimeSpinner.Value-1);
            
            if isempty(currMask)
                return
            end
            
            if isempty(prevMask)
                corrMask = currMask;
            else
                [corrMask, lostOnes] = cellCorrespondance2(prevMask, currMask);
            end
            
            if isempty(lostOnes)
                app.MissedcellsTextArea.Value = "";
            else
                app.MissedcellsTextArea.Value = num2str(lostOnes);
            end
            
            app.backup = currMask;
            setSegMask(app, corrMask)
            displayImg(app)
        end

        % Button pushed function: GOBACKButton
        function doGoBack(app, event)
            if isempty(app.backup)
                return
            end
            setSegMask(app, app.backup)
            displayImg(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Previous Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Current Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RegionSpinnerLabel = uilabel(app.UIFigure);
            app.RegionSpinnerLabel.Text = 'Region';
            app.RegionSpinner = uispinner(app.UIFigure);
            app.RegionSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeRegion, true);
            app.ChannelSpinnerLabel = uilabel(app.UIFigure);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.UIFigure);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeChannel, true);
            app.TimeSpinnerLabel = uilabel(app.UIFigure);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.UIFigure);
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @doChangeTime, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @doLoad, true);
            app.LoadButton.Text = 'Load';
            app.RedoCorrespondanceButton = uibutton(app.UIFigure, 'push');
            app.RedoCorrespondanceButton.ButtonPushedFcn = createCallbackFcn(app, @doCorrespondance, true);
            app.RedoCorrespondanceButton.Text = 'Redo Correspondance';
            app.MissedcellsTextAreaLabel = uilabel(app.UIFigure);
            app.MissedcellsTextAreaLabel.Text = 'Missed cells';
            app.MissedcellsTextArea = uitextarea(app.UIFigure);
            app.MissedcellsTextArea.Editable = 'off';
            app.GOBACKButton = uibutton(app.UIFigure, 'push');
            app.GOBACKButton.ButtonPushedFcn = createCallbackFcn(app, @doGoBack, true);
            app.GOBACKButton.Text = 'GO BACK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = correspondance

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end